classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Waterkgm3EditFieldLabel         matlab.ui.control.Label
        Waterkgm3EditField              matlab.ui.control.NumericEditField
        Cementkgm3EditFieldLabel        matlab.ui.control.Label
        Cementkgm3EditField             matlab.ui.control.NumericEditField
        FinenaturalaggregateEditFieldLabel  matlab.ui.control.Label
        FinenaturalaggregateEditField   matlab.ui.control.NumericEditField
        CoarsenaturalaggregateEditFieldLabel  matlab.ui.control.Label
        CoarsenaturalaggregateEditField  matlab.ui.control.NumericEditField
        FinerubberEditFieldLabel        matlab.ui.control.Label
        FinerubberEditField             matlab.ui.control.NumericEditField
        CoarserubberEditFieldLabel      matlab.ui.control.Label
        CoarserubberEditField           matlab.ui.control.NumericEditField
        SuperplasticizerEditFieldLabel  matlab.ui.control.Label
        SuperplasticizerEditField       matlab.ui.control.NumericEditField
        SilicafumeEditFieldLabel        matlab.ui.control.Label
        SilicafumeEditField             matlab.ui.control.NumericEditField
        FlyashEditFieldLabel            matlab.ui.control.Label
        FlyashEditField                 matlab.ui.control.NumericEditField
        CalculatecompressivestrengthButton  matlab.ui.control.Button
        MpaEditFieldLabel               matlab.ui.control.Label
        MpaEditField                    matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculatecompressivestrengthButton
        function CalculatecompressivestrengthButtonPushed(app, event)
        x1=app.Waterkgm3EditField.Value
        x2=app.Cementkgm3EditField.Value
        x3=app.FinenaturalaggregateEditField.Value
        x4=app.CoarsenaturalaggregateEditField.Value
        x5=app.FinerubberEditField.Value
        x6=app.CoarserubberEditField.Value
        x7=app.SuperplasticizerEditField.Value
        
        x8=app.SilicafumeEditField.Value
        x9=app.FlyashEditField.Value
        
        x=[x1,x2,x3,x4,x5,x6,x7,x8,x9]
        
        load('matlab.mat')
        y=trainedModel.predictFcn(x)
        app.MpaEditField.Value=y;
        end

        % Callback function
        function EditField2ValueChanged(app, event)
            app.MpaEditField.Value=y;
            
        end

        % Value changed function: MpaEditField
        function MpaEditFieldValueChanged2(app, event)
            value = app.MpaEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Waterkgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Waterkgm3EditFieldLabel.Text = 'Water (kg/m^3)';
            app.Waterkgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Cementkgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Cementkgm3EditFieldLabel.Text = 'Cement (kg/m^3)';
            app.Cementkgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.FinenaturalaggregateEditFieldLabel = uilabel(app.UIFigure);
            app.FinenaturalaggregateEditFieldLabel.Text = 'Fine natural aggregate';
            app.FinenaturalaggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoarsenaturalaggregateEditFieldLabel = uilabel(app.UIFigure);
            app.CoarsenaturalaggregateEditFieldLabel.Text = 'Coarse natural aggregate';
            app.CoarsenaturalaggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinerubberEditFieldLabel = uilabel(app.UIFigure);
            app.FinerubberEditFieldLabel.Text = 'Fine rubber';
            app.FinerubberEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoarserubberEditFieldLabel = uilabel(app.UIFigure);
            app.CoarserubberEditFieldLabel.Text = 'Coarse rubber';
            app.CoarserubberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuperplasticizerEditFieldLabel = uilabel(app.UIFigure);
            app.SuperplasticizerEditFieldLabel.Text = 'Superplasticizer';
            app.SuperplasticizerEditField = uieditfield(app.UIFigure, 'numeric');
            app.SilicafumeEditFieldLabel = uilabel(app.UIFigure);
            app.SilicafumeEditFieldLabel.Text = 'Silica fume';
            app.SilicafumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlyashEditFieldLabel = uilabel(app.UIFigure);
            app.FlyashEditFieldLabel.Text = 'Fly ash';
            app.FlyashEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculatecompressivestrengthButton = uibutton(app.UIFigure, 'push');
            app.CalculatecompressivestrengthButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatecompressivestrengthButtonPushed, true);
            app.CalculatecompressivestrengthButton.Text = 'Calculate compressive strength ';
            app.MpaEditFieldLabel = uilabel(app.UIFigure);
            app.MpaEditFieldLabel.Text = 'Mpa';
            app.MpaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MpaEditField.ValueChangedFcn = createCallbackFcn(app, @MpaEditFieldValueChanged2, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'SCRC CONCRETE COMPRESSIVE STRENGTH PREDICTION USING GAUSSIAN PROCESS'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end